classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        q6Slider       matlab.ui.control.Slider
        q6SliderLabel  matlab.ui.control.Label
        q5Slider       matlab.ui.control.Slider
        q5SliderLabel  matlab.ui.control.Label
        q4Slider       matlab.ui.control.Slider
        q4SliderLabel  matlab.ui.control.Label
        q3Slider       matlab.ui.control.Slider
        q3SliderLabel  matlab.ui.control.Label
        q2Slider       matlab.ui.control.Slider
        q2SliderLabel  matlab.ui.control.Label
        q1Slider       matlab.ui.control.Slider
        q1SliderLabel  matlab.ui.control.Label
    end

properties (Access = public)
        robot_1 % Robot
        q1_=0
        q2_=0
        q3_=0
        q4_=0
        q5_=0
        q6_=0
        fig
    end
    
    methods (Access = private)
        
        function updateRobotPlot(app)
            app.robot_1.plot([app.q1_,app.q2_,app.q3_,app.q4_,app.q5_,app.q6_],'workspace',[-10 10 -10 10 -1 12],'noa', 'view',[50 50])
                   
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
               L1 = 2.45;
            L2 = 3.78;
            L3 = 7.10;
            L4 = 3.00;
            L5 = 5.40;
            L6 = 1.50;
            L7 = 1.60;
            
            L(1) = Link('revolute','a',0,'alpha',0,'d',L1,'offset',0,'modified','qlim',[-pi,pi]);
            L(2) = Link('revolute','a',0,'alpha',pi/2,'d',-L2,'offset',pi/2,'modified','qlim',[-pi,pi]);
            L(3) = Link('revolute','a',L3,'alpha',pi,'d',-L4,'offset',0,'modified','qlim',[-3*pi/2,3*pi/2]);
            L(4) = Link('revolute','a',0,'alpha',-pi/2,'d',-L5,'offset',0,'modified','qlim',[-pi,pi]);
            L(5) = Link('revolute','a',0,'alpha',pi/2,'d',L6,'offset',0,'modified','qlim',[-pi,pi]);
            L(6) = Link('revolute','a',0,'alpha',-pi/2,'d',0,'offset',0,'modified','qlim',[-pi,pi]);
            
            % Cinemática directa
            A_tool = transl(0,0,-L7)*round(trotx(pi),2);
            app.robot_1 = SerialLink(L,'name','CRX-10iA');
            app.robot_1.tool = A_tool;
            app.fig = figure();
            updateRobotPlot(app)
            movegui(app.fig,'center');
            app.fig.WindowState = 'maximized';
        end

        % Value changing function: q6Slider
        function updateq6(app, event)
            app.q6_= event.Value;
            updateRobotPlot(app)
        end

        % Value changing function: q5Slider
        function updateq5(app, event)
            app.q5_= event.Value;
            updateRobotPlot(app)
        end

        % Value changing function: q4Slider
        function updateq4(app, event)
            app.q4_= event.Value;
            updateRobotPlot(app)
        end

        % Value changing function: q3Slider
        function updateq3(app, event)
            app.q3_= event.Value;
            updateRobotPlot(app)
        end

        % Value changing function: q2Slider
        function updateq2(app, event)
            app.q2_= event.Value;
            updateRobotPlot(app)
        end

        % Value changing function: q1Slider
        function updateq1(app, event)
            app.q1_= event.Value;
            updateRobotPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [0 6.28318530717959];
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @updateq1, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [0 6.28318530717959];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @updateq2, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [0 6.28318530717959];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @updateq3, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [0 6.28318530717959];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @updateq4, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [0 6.28318530717959];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @updateq5, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [0 6.28318530717959];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @updateq6, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end